classdef GUI_API_FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        background                  matlab.ui.control.CheckBox
        colorOverlap                matlab.ui.control.DropDown
        ColorofoverlaplayerLabel    matlab.ui.control.Label
        movieTime                   matlab.ui.control.NumericEditField
        TimeStepsLabel_6            matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        intensityVar                matlab.ui.control.NumericEditField
        TimeStepsLabel_5            matlab.ui.control.Label
        BrainSegmentationmodeLabel  matlab.ui.control.Label
        SlicesmodeLabel             matlab.ui.control.Label
        MoviemodeLabel              matlab.ui.control.Label
        AllappLabel                 matlab.ui.control.Label
        SliceTransversal            matlab.ui.control.NumericEditField
        TimeStepsLabel_4            matlab.ui.control.Label
        SliceCoronal                matlab.ui.control.NumericEditField
        TimeStepsLabel_3            matlab.ui.control.Label
        SliceSagital                matlab.ui.control.NumericEditField
        TimeStepsLabel_2            matlab.ui.control.Label
        ColorMap                    matlab.ui.control.DropDown
        ColorMapDropDownLabel       matlab.ui.control.Label
        SliceTime                   matlab.ui.control.NumericEditField
        TimeStepsLabel              matlab.ui.control.Label
        PLAYButton                  matlab.ui.control.Button
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        SETTINGSLabel               matlab.ui.control.Label
        DMRIBRAINSCANwithsegmentationLabel  matlab.ui.control.Label
        Segmentation                matlab.ui.container.ButtonGroup
        YesButton                   matlab.ui.control.RadioButton
        NoButton                    matlab.ui.control.RadioButton
        TransversalPlanLabel        matlab.ui.control.Label
        CoronalPlanLabel            matlab.ui.control.Label
        SagittalPlanLabel           matlab.ui.control.Label
        sagital                     matlab.ui.control.UIAxes
        transversal                 matlab.ui.control.UIAxes
        coronal                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startFc(app)
            % Função de iniciação da APP
            map=all_colormaps(app.ColorMap.Value);
            [sp,cp,tp,maximum]=all_plans();
            slice_representation(sp{app.SliceSagital.Value},app.sagital,map,maximum,0,0)
            slice_representation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,0,0)
            slice_representation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,0,0)
        end

        % Button pushed function: PLAYButton
        function playFc(app, event)
            % Modo Vídeo
            map=all_colormaps(app.ColorMap.Value);
            [sp,cp,tp,maximum]=all_plans();
            for i=1:length(sp)
                app.SliceSagital.Value=i;
                % A segmentação só é iniciada para slices em que o cérebro
                % surge representado (mesmo passo executado nos outros cortes)
                if (strcmp(app.Segmentation.SelectedObject.Text,'Yes') && app.SliceSagital.Value>=70 && app.SliceSagital.Value<=200)
                    segmentation(sp{app.SliceSagital.Value},app.sagital,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(sp{i},app.sagital,map,maximum,0,0)
                end

                app.SliceCoronal.Value=i;
                if (strcmp(app.Segmentation.SelectedObject.Text,'Yes') && app.SliceCoronal.Value>=30 && app.SliceCoronal.Value<=200)
                    segmentation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(cp{i},app.coronal,map,maximum,0,0)
                end

                app.SliceTransversal.Value=i;
                if (strcmp(app.Segmentation.SelectedObject.Text,'Yes') && app.SliceTransversal.Value>=60 && app.SliceTransversal.Value<=180)
                    segmentation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(tp{i},app.transversal,map,maximum,0,0)
                end

                % Pausa temporal entre apresntação dos slices
                pause(app.movieTime.Value);
            end

            pause(1)

            % Após 2 segundo do término da apresentação vídeo, a app
            % retorna ao estado da aplicação inicial
            % Apresentação dos slices anteriores com animação de intervalos
            % de intensidades
            
            if (strcmp(app.Segmentation.SelectedObject.Text,'Yes'))
                app.SliceSagital.Value=126;
                segmentation(sp{app.SliceSagital.Value},app.sagital,map,maximum,app.colorOverlap.Value,app.background.Value);
                app.SliceCoronal.Value=126;
                segmentation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,app.colorOverlap.Value,app.background.Value);
                app.SliceTransversal.Value=126;
                segmentation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,app.colorOverlap.Value,app.background.Value);
            else
                app.SliceSagital.Value=126;
                slice_representation(sp{app.SliceSagital.Value},app.sagital,map,maximum,app.intensityVar.Value/100,app.SliceTime.Value);
                app.SliceCoronal.Value=126;
                slice_representation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,app.intensityVar.Value/100,app.SliceTime.Value);
                app.SliceTransversal.Value=126;
                slice_representation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,app.intensityVar.Value/100,app.SliceTime.Value);
            end


        end

        % Value changed function: ColorMap
        function colormapFc(app, event)
            startFc(app);
        end

        % Value changed function: SliceSagital
        function sliceSagitalFc(app, event)
            map=all_colormaps(app.ColorMap.Value);
            [sp,~,~,maximum]=all_plans();
            % Apresentação dos resultados caso haja mudança no slice do
            % plano sagital
            if (strcmp(app.Segmentation.SelectedObject.Text,'Yes'))
                if(app.SliceSagital.Value>=70 && app.SliceSagital.Value<=200)
                    segmentation(sp{app.SliceSagital.Value},app.sagital,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(sp{app.SliceSagital.Value},app.sagital,map,maximum,0,0);
                end
            else
                slice_representation(sp{app.SliceSagital.Value},app.sagital,map,maximum,app.intensityVar.Value/100,app.SliceTime.Value);
            end
        end

        % Value changed function: SliceCoronal
        function sliceCoronalFc(app, event)
            map=all_colormaps(app.ColorMap.Value);
            [~,cp,~,maximum]=all_plans();
            % Apresentação dos resultados caso haja mudança no slice do
            % plano coronal
            if (strcmp(app.Segmentation.SelectedObject.Text,'Yes'))
                if(app.SliceCoronal.Value>=30 && app.SliceCoronal.Value<=200)
                    segmentation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,0,0);
                end
            else
                slice_representation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,app.intensityVar.Value/100,app.SliceTime.Value);
            end
        end

        % Value changed function: SliceTransversal
        function sliceTransversalFc(app, event)
            map=all_colormaps(app.ColorMap.Value);
            [~,~,tp,maximum]=all_plans();
            % Apresentação dos resultados caso haja mudança no slice do
            % plano transversal
            if (strcmp(app.Segmentation.SelectedObject.Text,'Yes'))
                if(app.SliceTransversal.Value>=60 && app.SliceTransversal.Value<=180)
                    segmentation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,0,0);
                end
            else
                slice_representation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,app.intensityVar.Value/100,app.SliceTime.Value);
            end
        end

        % Selection changed function: Segmentation
        function segmetationFc(app, event)
            map=all_colormaps(app.ColorMap.Value);
            [sp,cp,tp,maximum]=all_plans();
            % Caso haja ativação da segmentação, será sempre amostrado como
            % plano de fundo a imagem original
            app.background.Value=0;

            % Representação das imagens segmentadas
            if strcmp(app.Segmentation.SelectedObject.Text,'Yes')

                if (app.SliceSagital.Value>=70 && app.SliceSagital.Value<=200)
                    segmentation(sp{app.SliceSagital.Value},app.sagital,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(sp{app.SliceSagital.Value},app.sagital,map,maximum,0,0);
                end

                if (app.SliceCoronal.Value>=30 && app.SliceCoronal.Value<=200)
                    segmentation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,0,0);
                end

                if (app.SliceTransversal.Value>=60 && app.SliceTransversal.Value<=180)
                    segmentation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,0,0);
                end

            else
                slice_representation(sp{app.SliceSagital.Value},app.sagital,map,maximum,0,0);
                slice_representation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,0,0);
                slice_representation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,0,0);
            end
        end

        % Value changed function: background
        function backgroudFc(app, event)
            map=all_colormaps(app.ColorMap.Value);
            [sp,cp,tp,maximum]=all_plans();

            % Representação da imagem do slice em causa caso haja mudanças
            % ao nível do Background
            if strcmp(app.Segmentation.SelectedObject.Text,'Yes')
                if (app.SliceSagital.Value>=70 && app.SliceSagital.Value<=200)
                    segmentation(sp{app.SliceSagital.Value},app.sagital,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(sp{app.SliceSagital.Value},app.sagital,map,maximum,0,0);
                end

                if (app.SliceCoronal.Value>=30 && app.SliceCoronal.Value<=200)
                    segmentation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,0,0);
                end

                if (app.SliceTransversal.Value>=60 && app.SliceTransversal.Value<=180)
                    segmentation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,0,0);
                end
            end
        end

        % Value changed function: colorOverlap
        function colorOverlapValueChanged(app, event)
            map=all_colormaps(app.ColorMap.Value);
            [sp,cp,tp,maximum]=all_plans();
            app.background.Value=0;

            % Representação da imagem do slice em causa caso haja mudanças
            % ao nível da cor da área segmentada
            if strcmp(app.Segmentation.SelectedObject.Text,'Yes')

                if (app.SliceSagital.Value>=70 && app.SliceSagital.Value<=200)
                    segmentation(sp{app.SliceSagital.Value},app.sagital,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(sp{app.SliceSagital.Value},app.sagital,map,maximum,0,0);
                end

                if (app.SliceCoronal.Value>=30 && app.SliceCoronal.Value<=200)
                    segmentation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(cp{app.SliceCoronal.Value},app.coronal,map,maximum,0,0);
                end

                if (app.SliceTransversal.Value>=60 && app.SliceTransversal.Value<=180)
                    segmentation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,app.colorOverlap.Value,app.background.Value);
                else
                    slice_representation(tp{app.SliceTransversal.Value},app.transversal,map,maximum,0,0);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.coronal = uiaxes(app.UIFigure);
            app.coronal.XTick = [];
            app.coronal.XTickLabel = '';
            app.coronal.YTick = [];
            app.coronal.ZTick = [];
            app.transversal = uiaxes(app.UIFigure);
            app.transversal.XTick = [];
            app.transversal.XTickLabel = '';
            app.transversal.YTick = [];
            app.transversal.ZTick = [];
            app.sagital = uiaxes(app.UIFigure);
            app.sagital.XTick = [];
            app.sagital.XTickLabel = '';
            app.sagital.YTick = [];
            app.sagital.ZTick = [];
            app.SagittalPlanLabel = uilabel(app.UIFigure);
            app.SagittalPlanLabel.Text = 'Sagittal Plan';
            app.CoronalPlanLabel = uilabel(app.UIFigure);
            app.CoronalPlanLabel.Text = 'Coronal Plan';
            app.TransversalPlanLabel = uilabel(app.UIFigure);
            app.TransversalPlanLabel.Text = 'Transversal Plan';
            app.Segmentation = uibuttongroup(app.UIFigure);
            app.Segmentation.SelectionChangedFcn = createCallbackFcn(app, @segmetationFc, true);
            app.NoButton = uiradiobutton(app.Segmentation);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.YesButton = uiradiobutton(app.Segmentation);
            app.YesButton.Text = 'Yes';
            app.DMRIBRAINSCANwithsegmentationLabel = uilabel(app.UIFigure);
            app.DMRIBRAINSCANwithsegmentationLabel.Text = '3D MRI BRAIN SCAN with segmentation';
            app.SETTINGSLabel = uilabel(app.UIFigure);
            app.SETTINGSLabel.Text = 'SETTINGS ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '______________________________________________________________________________________';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '______________________________________________________________________________________';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @playFc, true);
            app.PLAYButton.Icon = fullfile(pathToMLAPP, 'play_button.png');
            app.PLAYButton.Text = 'PLAY';
            app.TimeStepsLabel = uilabel(app.UIFigure);
            app.TimeStepsLabel.Text = 'Time step between variation of intensities:';
            app.SliceTime = uieditfield(app.UIFigure, 'numeric');
            app.SliceTime.Limits = [0 Inf];
            app.SliceTime.Value = 0.1;
            app.ColorMapDropDownLabel = uilabel(app.UIFigure);
            app.ColorMapDropDownLabel.Text = 'Color Map:';
            app.ColorMap = uidropdown(app.UIFigure);
            app.ColorMap.Items = {'gray', 'hot', 'bone', 'copper', 'turbo'};
            app.ColorMap.ValueChangedFcn = createCallbackFcn(app, @colormapFc, true);
            app.ColorMap.Value = 'gray';
            app.TimeStepsLabel_2 = uilabel(app.UIFigure);
            app.TimeStepsLabel_2.Text = 'Sagittal Slice:';
            app.SliceSagital = uieditfield(app.UIFigure, 'numeric');
            app.SliceSagital.Limits = [1 256];
            app.SliceSagital.ValueChangedFcn = createCallbackFcn(app, @sliceSagitalFc, true);
            app.SliceSagital.Value = 128;
            app.TimeStepsLabel_3 = uilabel(app.UIFigure);
            app.TimeStepsLabel_3.Text = 'Coronal Slice:';
            app.SliceCoronal = uieditfield(app.UIFigure, 'numeric');
            app.SliceCoronal.Limits = [1 256];
            app.SliceCoronal.ValueChangedFcn = createCallbackFcn(app, @sliceCoronalFc, true);
            app.SliceCoronal.Value = 128;
            app.TimeStepsLabel_4 = uilabel(app.UIFigure);
            app.TimeStepsLabel_4.Text = 'Transversal Slice:';
            app.SliceTransversal = uieditfield(app.UIFigure, 'numeric');
            app.SliceTransversal.Limits = [1 256];
            app.SliceTransversal.ValueChangedFcn = createCallbackFcn(app, @sliceTransversalFc, true);
            app.SliceTransversal.Value = 128;
            app.AllappLabel = uilabel(app.UIFigure);
            app.AllappLabel.Text = 'All app:';
            app.MoviemodeLabel = uilabel(app.UIFigure);
            app.MoviemodeLabel.Text = 'Movie mode:';
            app.SlicesmodeLabel = uilabel(app.UIFigure);
            app.SlicesmodeLabel.Text = 'Slices mode:';
            app.BrainSegmentationmodeLabel = uilabel(app.UIFigure);
            app.BrainSegmentationmodeLabel.Text = 'Brain Segmentation mode:';
            app.TimeStepsLabel_5 = uilabel(app.UIFigure);
            app.TimeStepsLabel_5.Text = 'Intensities Variation (%):';
            app.intensityVar = uieditfield(app.UIFigure, 'numeric');
            app.intensityVar.Limits = [0 100];
            app.intensityVar.Value = 5;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Interpreter = 'latex';
            app.Label_4.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Interpreter = 'latex';
            app.Label_5.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.TimeStepsLabel_6 = uilabel(app.UIFigure);
            app.TimeStepsLabel_6.Text = 'Time Steps:';
            app.movieTime = uieditfield(app.UIFigure, 'numeric');
            app.movieTime.Limits = [0 10];
            app.movieTime.Value = 0.01;
            app.ColorofoverlaplayerLabel = uilabel(app.UIFigure);
            app.ColorofoverlaplayerLabel.Text = 'Color of overlap layer:';
            app.colorOverlap = uidropdown(app.UIFigure);
            app.colorOverlap.Items = {'red', 'green', 'blue', 'white', 'black'};
            app.colorOverlap.ValueChangedFcn = createCallbackFcn(app, @colorOverlapValueChanged, true);
            app.colorOverlap.Value = 'red';
            app.background = uicheckbox(app.UIFigure);
            app.background.ValueChangedFcn = createCallbackFcn(app, @backgroudFc, true);
            app.background.Text = 'Without backgroud';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_API_FinalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startFc)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end